classdef Forcecal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ShowpicButton              matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        VideodealButton            matlab.ui.control.Button
        DecTrainingButton          matlab.ui.control.Button
        DatadealButton             matlab.ui.control.Button
        ResultshowButton           matlab.ui.control.Button
        OutputEditField            matlab.ui.control.EditField
        OutputEditFieldLabel       matlab.ui.control.Label
        TrainStepmNEditField       matlab.ui.control.NumericEditField
        TrainStepmNEditFieldLabel  matlab.ui.control.Label
        TrainAimmNEditField        matlab.ui.control.NumericEditField
        TrainAimmNEditFieldLabel   matlab.ui.control.Label
        ClearButton                matlab.ui.control.Button
        SampleindexEditField       matlab.ui.control.NumericEditField
        SampleindexEditFieldLabel  matlab.ui.control.Label
        SavingpathTextArea         matlab.ui.control.TextArea
        SavingpathTextAreaLabel    matlab.ui.control.Label
        TrainingButton             matlab.ui.control.Button
        TrainControlButton         matlab.ui.control.Button
        TrainStartButton           matlab.ui.control.Button
        LocomotionTextArea         matlab.ui.control.TextArea
        LocomotionLabel            matlab.ui.control.Label
        AimforcemNEditField        matlab.ui.control.NumericEditField
        AimforcemNEditFieldLabel   matlab.ui.control.Label
        ControlButton              matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        ForceLabel                 matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    isRunning = false;           % 用于控制数据采集是否进行的开关
    index = 1;                   % 当前数据索引
    timeData = zeros(1, 100);    % 存储时间数据的数组
    forceData = zeros(1, 100);   % 存储力数据的数组
    locomotionData=zeros(1, 100); % 储存行为数据的数组
    isCondition1Met = false;     % 第一阈值
    isCondition2Met = false;     % 第二阈值
    isCondition3Met = false;     % 普通控制态
    isCondition4Met = false;     % 训练控制态
    isCondition5Met = false;     % 训练启动态
    isCondition6Met = false;
    isforcedecdone =  false;
    firsttimedec=true;
    forcedeccal=900;
    s                        % 串口对象，用于与传感器通信
    m                        % 串口对象，用于电机控制
    request                  % 请求传感器数据的指令
    locomotion=0;            % 记录行为
    isokcontrol=false;
    dealindex=1;
    existing_calculation_results={};
    end
 
methods (Access = private)
    % 在App的构造函数或启动函数中添加以下行：

% 定义回调函数
function keyPressFcn(app, event)
    switch event.Key
        case 'q'
            app.locomotion = 1; % 前进
            app.LocomotionTextArea.Value='FWD';
        case 'w'
            app.locomotion = -1; % 后退
            app.LocomotionTextArea.Value='BWD';
    end
end

% 键盘释放事件处理函数
function keyReleaseFcn(app, event)
    if ismember(event.Key, {'q', 'w'})
        app.locomotion = 0; % 停止
        app.LocomotionTextArea.Value='STATIC';
    end
end

function startMeasurement(app)
    aimforce=app.AimforcemNEditField.Value;
    app.isRunning = true;
    app.index = 1;
    app.timeData = zeros(1, 100);
    app.forceData = zeros(1, 100);
    app.isCondition1Met = false;
    app.isCondition2Met = false;
    tic; % 开始计时
    connect2motor(app.m, "MODE_V 0");
    connect2motor(app.m, "V_ACC_DEC 300 800");
    connect2motor(app.m, "V_ABS 0 -250");
    % 主循环，读取和更新数据
    savedir=string(strcat(app.SavingpathTextArea.Value,'\S',num2str(app.SampleindexEditField.Value),'\'));
    if ~exist(string(strcat(app.SavingpathTextArea.Value,'\S',num2str(app.SampleindexEditField.Value))),"dir")
        mkdir(string(strcat(app.SavingpathTextArea.Value,'\S',num2str(app.SampleindexEditField.Value))));
    end
    videoFilename = strcat(savedir,'recorded_video.avi')
    v = VideoWriter(videoFilename);
    v.FrameRate = 5; % 设置帧率，根据需要调整
    open(v);

    while app.isRunning

        elapsedTime = toc; % 获取经过的时间
        write(app.s, app.request, "uint8");
        outdec = read(app.s, 9, "uint8");
        if length(outdec) < 9
            continue; % 跳过数据长度不足的情况
        else
            outhex = dec2hex(outdec);
            outstr = reshape(outhex.', 1, []);
            Forcedata = hex2dec(outstr(7:14)) * 0.1; % 提取力数据
            if Forcedata > 5000
                continue; % 如果力数据无效则跳过
            end
            % 更新数据和绘图
            app.timeData(app.index) = elapsedTime;
            app.forceData(app.index) = Forcedata;
            app.locomotionData(app.index)=app.locomotion;
            plot(app.UIAxes, app.timeData(1:app.index), app.forceData(1:app.index), 'b-');
            app.ForceLabel.Text = sprintf('Force: %.1f mN', Forcedata); % 更新力值显示
            drawnow;
            app.index = app.index + 1;
            % 条件检查与电机控制
            if ~app.isCondition1Met && Forcedata > aimforce-10
                try
                    connect2motor(app.m, "V_ABS 0 -50");
                    app.OutputEditField.Value="Plat A";
                    app.isCondition1Met = true;
                catch
                    app.OutputEditField.Value="Wrong";
                end
            end
            if ~app.isCondition2Met && Forcedata > aimforce-5
                try
                    connect2motor(app.m, "HALT_ONE 0");
                    app.OutputEditField.Value="Plat B";
                    app.isCondition2Met = true;
                catch
                    app.OutputEditField.Value="Wrong";
                end
            end
            if app.isCondition3Met && Forcedata>aimforce-5
                connect2motor(app.m, "V_ABS 0 50");
            end
            if app.isCondition3Met && Forcedata<aimforce+5
                connect2motor(app.m, "V_ABS 0 -50");
            end
            larvaframe=screencapture(0,[0,-80,1200,1080]);
            writeVideo(v, larvaframe); % 将捕获的帧写入视频
            pause(0.01); % 根据需要调整实时更新率
        end
    end
       close(v);
end

        
function stopMeasurement(app)
    app.isRunning = false;
    app.isCondition1Met=false;
    app.isCondition2Met=false;
    app.isCondition3Met=false;
    app.isCondition4Met=false;
    app.isCondition5Met=false;
    app.isCondition6Met=false;
    timeData = app.timeData;
    forceData = app.forceData;
    locomotionData = app.locomotionData;
    savedir=string(strcat(app.SavingpathTextArea.Value,'\S',num2str(app.SampleindexEditField.Value),'\time_force_locomotiondata'));
    if ~exist(string(strcat(app.SavingpathTextArea.Value,'\S',num2str(app.SampleindexEditField.Value))),"dir")
        mkdir(string(strcat(app.SavingpathTextArea.Value,'\S',num2str(app.SampleindexEditField.Value))));
    end
    save(savedir, 'timeData', 'forceData', 'locomotionData');
    connect2motor(app.m,"V_STOP 0");
    app.OutputEditField.Value="PROGRAM STOP";
end


function trainMeasurement(app)
    aimforce=app.TrainAimmNEditField.Value;
    app.isRunning = true;
    app.index = 1;
    app.timeData = zeros(1, 100);
    app.forceData = zeros(1, 100);
    app.isCondition1Met = false;
    app.isCondition2Met = false;
    tic; % 开始计时
    connect2motor(app.m, "MODE_V 0");
    connect2motor(app.m, "V_ACC_DEC 500 8000");
    connect2motor(app.m, "V_ABS 0 -350");

    while app.isRunning
        elapsedTime = toc; % 获取经过的时间
        write(app.s, app.request, "uint8");
        outdec = read(app.s, 9, "uint8");
        if length(outdec) < 9
            continue; % 跳过数据长度不足的情况
        else
            outhex = dec2hex(outdec);
            outstr = reshape(outhex.', 1, []);
            Forcedata = hex2dec(outstr(7:14)) * 0.1; % 提取力数据

            if Forcedata > 500
                continue; % 如果力数据无效则跳过
            end
            % 更新数据和绘图
            app.timeData(app.index) = elapsedTime;
            app.forceData(app.index) = Forcedata;
            app.locomotionData(app.index)=app.locomotion;
            plot(app.UIAxes, app.timeData(1:app.index), app.forceData(1:app.index), 'b-');
            app.ForceLabel.Text = sprintf('Force: %.1f mN', Forcedata); % 更新力值显示
            drawnow;
            app.index = app.index + 1;
            % 条件检查与电机控制
            if ~app.isCondition1Met && Forcedata > aimforce-25
                try
                    connect2motor(app.m, "V_ABS 0 -50");
                    app.isCondition1Met = true;
                end
            end
            if ~app.isCondition2Met && Forcedata > aimforce-10
                try
                    connect2motor(app.m, "HALT_ONE 0");
                    app.isCondition2Met = true;
                end
            end
            if app.isCondition4Met && Forcedata>aimforce-10 
                connect2motor(app.m, "V_ABS 0 50");
            end
            if app.isCondition4Met&&Forcedata<aimforce+5&&Forcedata>aimforce-5
                connect2motor(app.m, "HALT_ONE 0");
            end
            if app.isCondition4Met && Forcedata<aimforce+10
                connect2motor(app.m, "V_ABS 0 -50");
            end
            if app.isCondition5Met && Forcedata>app.forcedeccal-app.TrainStepmNEditField.Value && app.firsttimedec
                connect2motor(app.m, "V_ABS 0 50");
                app.firsttimedec=false;
                app.OutputEditField.Value="Downing";
            end
            if app.isCondition5Met && Forcedata<app.forcedeccal-app.TrainStepmNEditField.Value &&~app.firsttimedec
                connect2motor(app.m, "HALT_ONE 0");
                app.firsttimedec=true;
                app.OutputEditField.Value="Arrived";
                app.isCondition5Met=false;
            end     
           if app.isCondition6Met && Forcedata>app.forcedeccal-app.TrainStepmNEditField.Value && app.firsttimedec
                connect2motor(app.m, "V_ABS 0 -50");
                app.firsttimedec=false;
                app.OutputEditField.Value="Downing";
            end
            if app.isCondition6Met && Forcedata<app.forcedeccal-app.TrainStepmNEditField.Value &&~app.firsttimedec
                connect2motor(app.m, "HALT_ONE 0");
                app.firsttimedec=true;
                app.OutputEditField.Value="Arrived";
                app.isCondition6Met=false;
            end  
            pause(0.1); % 根据需要调整实时更新率
        end
    end
end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 在 App 的初始化部分添加串口对象的创建
            try
                app.m = serialport('COM3', 115200, 'DataBits', 8, 'StopBits', 1, 'Parity', 'None', 'Timeout', 1);
                app.s = serialport('COM4', 9600, 'DataBits', 8, 'StopBits', 1, 'Parity', 'None', 'Timeout', 1);
                app.request = uint8(hex2dec(['01'; '03'; '00'; '50'; '00'; '02'; 'C4'; '1A'])); % 初始化请求指令
                app.OutputEditField.Value="COM ready";
                app.UIFigure.KeyPressFcn = @(src, event) keyPressFcn(app, event);
                app.UIFigure.KeyReleaseFcn = @(src, event) keyReleaseFcn(app, event);
            catch
               app.OutputEditField.Value="COM wrong";
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            startMeasurement(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            
            stopMeasurement(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

    aimforce=1;
    app.isRunning = true;
    app.isCondition1Met = false;
    app.isCondition2Met = false;
    connect2motor(app.m, "MODE_V 0");
    connect2motor(app.m, "V_ACC_DEC 500 800");
    connect2motor(app.m, "V_ABS 0 300");
    pause(2)
    connect2motor(app.m, "HALT_ONE 0");
    % while app.isRunning
    %     write(app.s, app.request, "uint8");
    %     outdec = read(app.s, 9, "uint8");
    %     if length(outdec) < 9
    %         continue; % 跳过数据长度不足的情况
    %     else
    %         outhex = dec2hex(outdec);
    %         outstr = reshape(outhex.', 1, []);
    %         Forcedata = hex2dec(outstr(7:14)) * 0.1; % 提取力数据
    %         if Forcedata > 500
    %             continue;
    %         end
    %         if Forcedata < aimforce
    %             try
    %                 connect2motor(app.m, "HALT_ONE 0");
    %                 break
    %             end
    %         end
    % 
    %         pause(0.1); % 根据需要调整实时更新率
    %     end
    % end


        end

        % Button pushed function: ControlButton
        function ControlButtonPushed(app, event)
            app.isCondition3Met = true;
            app.isCondition1Met=true;
            app.isCondition2Met=true;
            app.isCondition5Met=false;
        end

        % Button pushed function: TrainStartButton
        function TrainStartButtonPushed(app, event)
            trainMeasurement(app);
        end

        % Button pushed function: TrainControlButton
        function TrainControlButtonPushed(app, event)
            app.isCondition4Met = true;
        end

        % Button pushed function: TrainingButton
        function TrainingButtonPushed(app, event)
            app.isCondition4Met = false;
            app.isCondition5Met = true;
            app.forcedeccal=app.forceData(app.index-1);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Button pushed function: ResultshowButton
        function ResultshowButtonPushed(app, event)
[file, path] = uigetfile('*.mat', 'Select the MAT-file');
if isequal(file, 0) || isequal(path, 0)
   disp('User pressed cancel');
else
   data = load(fullfile(path, file));
   % 假设读取的数据包含 timeData, forceData 和 locomotionData
   app.timeData = data.timeData;
   app.forceData = data.forceData;
   app.locomotionData = data.locomotionData;
   
   % 获取三个变量的最短长度
   minLength = min([length(app.timeData), length(app.forceData), length(app.locomotionData)]);
   
   % 截取三个变量到最短长度
   app.timeData = app.timeData(1:minLength);
   app.forceData = app.forceData(1:minLength);
   app.locomotionData = app.locomotionData(1:minLength);
   
   % 绘制图形
   plot(app.UIAxes, app.timeData, app.forceData, 'b-');  % 绘制 force vs. time
   app.UIAxes.NextPlot = 'add';  % 使用 'hold on' 的效果
   plot(app.UIAxes, app.timeData, app.locomotionData, 'r-');  % 绘制 locomotion vs. time
   app.UIAxes.NextPlot = 'replace';  % 恢复为 'hold off'
end

        end

        % Button pushed function: DatadealButton
        function DatadealButtonPushed(app, event)
    % 选择并加载 MAT 文件
    [file, path] = uigetfile('*.mat', 'Select the MAT-file');
    if isequal(file, 0) || isequal(path, 0)
        disp('User canceled file selection');
        return;
    end

    % 加载数据
    data = load(fullfile(path, file));
    
    app.timeData = data.timeData(120:end);
    app.timeData=app.timeData-app.timeData(1);
    app.forceData = data.forceData(120:end);
    app.locomotionData = data.locomotionData(120:end);
    
  
    t = app.timeData;
    force = app.forceData;
    newall = app.locomotionData;

    tempindex = find(force > 1, 1, 'first');
    t=t(1:end-tempindex);
    force=force(tempindex:end);
    newall=newall(tempindex:end);
   % 截取三个变量到最短长度
    minLength = min([length(t), length(force), length(newall)]);
    t = t(1:minLength);
    force = force(1:minLength);
    newall = newall(1:minLength);
    % 设定时长限制
    Timelimit = 2 * 60 * 30; % 2分钟为上限，即3600帧
    force = limit_matrix_rows(force, Timelimit);
    newall = limit_matrix_rows(newall, Timelimit);
    % 计算时间总长，单位为秒
    total_time_seconds = t(end) - t(1);
    % 计算前进蠕动的段数和发生时间
    % 从 0 或 -1 变为 1 的位置
    forward_idx = find(newall(2:end) == 1 & newall(1:end-1) ~= 1) + 1;
    forward_times = t(forward_idx);  % 找到前进发生的绝对时间
    relative_forward_times = (forward_times - t(1)) / total_time_seconds;  % 计算相对时间

    % 计算后退蠕动的段数和发生时间
    % 从 0 或 1 变为 -1 的位置
    backward_idx = find(newall(2:end) == -1 & newall(1:end-1) ~= -1) + 1;
    backward_times = t(backward_idx);  % 找到后退发生的绝对时间
    relative_backward_times = (backward_times - t(1)) / total_time_seconds;  % 计算相对时间

    % 输出前进蠕动频率，单位为 Hz (次/秒)
    forward_frequency = numel(forward_idx) / total_time_seconds;
    backward_frequency = numel(backward_idx) / total_time_seconds;
    % 峰值检测
    temp = force;
    [peaks, peak_locs, ~, p] = findpeaks(temp, 'MinPeakDistance', 1 * 30, 'MinPeakProminence', 5);
    peak_time = t(peak_locs);
    % 数据平滑
    smoothed_data = smoothdata(temp, 'movmean', 900);

    % 统计 locomotion 行为数据

    % 在 app.UIAxes 上绘制图形
    cla(app.UIAxes); % 清空旧图像
    hold(app.UIAxes, 'on'); % 保持绘图

    % 绘制行为标记
    scatter(app.UIAxes, t(newall == 1), newall(newall == 1), [], 'red', '.', 'LineWidth', 0.5, 'MarkerFaceColor', 'none');
    scatter(app.UIAxes, t(newall == -1), newall(newall == -1), [], 'blue', '.', 'LineWidth', 0.5, 'MarkerFaceColor', 'none');

    % 绘制力值曲线和峰值
    plot(app.UIAxes, t, force, 'black', 'LineWidth', 2);
    plot(app.UIAxes, t(peak_locs), peaks, 'Color', 'black', 'Marker', 'o', 'MarkerSize', 12, 'MarkerFaceColor', 'none', 'MarkerEdgeColor', 'red');

    % 设置轴标签和图形属性
    xlabel(app.UIAxes, 'Time (s)');
    ylabel(app.UIAxes, 'Force (mN)');
    ylim(app.UIAxes, [-2, max(force)+5]);
    xlim(app.UIAxes, [0, 120]);
    title(app.UIAxes, 'Force and Locomotion');
    hold(app.UIAxes, 'off');

    % 计算指标
    time_diff = diff(peak_time);
    peak_frequency = 1 ./ time_diff;
    average_frequency = mean(peak_frequency);
    max_intervals = max(time_diff);
    min_intervals = min(time_diff);
    maxforceadd = max(diff(peaks));
    maxforcedec = min(diff(peaks));
    num_peaks = numel(peaks);
    start_time = min(peak_time);
    end_time = max(peak_time);
    duration = end_time - start_time;

    % 计算峰值变化率
    peak_diff = diff(peaks);
    peak_change_rate = peak_diff ./ time_diff;
    num_rising = sum(peak_change_rate > 0.1); % 上升的次数
    num_falling = sum(peak_change_rate < -0.1); % 下降的次数

    % 创建指标表格
    indicators_table = table(forward_frequency,backward_frequency,average_frequency, max_intervals, min_intervals, maxforceadd, maxforcedec, num_peaks, start_time, end_time, duration, num_rising, num_falling);

    % 获取上两级文件夹的路径和名称
    upper_folder_path = fileparts(fileparts(path));  % 获取上两级路径
    [~, grandparent_folder_name] = fileparts(upper_folder_path);  % 获取上两级母文件夹名称
    
    % 设置 Excel 文件保存路径
    excel_file_path = fullfile(upper_folder_path, [grandparent_folder_name, '.xls']);

    % 检查是否已有 Excel 文件并处理数据
    if exist(excel_file_path, 'file') == 2
        % 读取已有数据
        existing_data = readtable(excel_file_path);

        % 检查列名是否一致
        if ~isequal(existing_data.Properties.VariableNames, indicators_table.Properties.VariableNames)
            % 调整列名以匹配已有数据
            common_columns = intersect(existing_data.Properties.VariableNames, indicators_table.Properties.VariableNames);
            missing_columns_existing = setdiff(existing_data.Properties.VariableNames, common_columns);
            missing_columns_new = setdiff(indicators_table.Properties.VariableNames, common_columns);

            % 补齐缺失列
            for col = missing_columns_existing
                indicators_table.(col{1}) = NaN(height(indicators_table), 1);
            end
            for col = missing_columns_new
                existing_data.(col{1}) = NaN(height(existing_data), 1);
            end

            % 重新排序列
            indicators_table = indicators_table(:, existing_data.Properties.VariableNames);
        end

        % 检查 'Path' 列
        if any(strcmp(existing_data.Properties.VariableNames, 'Path'))
            combined_data = [existing_data; indicators_table];
        else
            path_info = string(upper_folder_path);
            tempindicators_table = [table(path_info, 'VariableNames', {'Path'}), indicators_table];
            combined_data = [existing_data; tempindicators_table];
        end
    else
        % 若 Excel 文件不存在，创建并保存新表格
        path_info = string(upper_folder_path);
        combined_data = [table(path_info, 'VariableNames', {'Path'}), indicators_table];
    end

    % 将数据写入 Excel 文件
    writetable(combined_data, excel_file_path);
    [~, filename, ~] = fileparts(file);  % 获取 MAT 文件的名称，不带扩展名
    png_file_path = fullfile(path, [filename, '.png']);  % 保存路径和文件名

    % 使用 exportgraphics 将 UIAxes 保存为 PNG
    exportgraphics(app.UIAxes, png_file_path, 'Resolution', 300);  % 高分辨率保存图像
    disp(['Data processed and saved at: ', excel_file_path]);
% 假设已经执行完前面的代码，计算完所有指标和结果

% 前进行为 (fwd) 的开始和结束
fwd_start_idx = find(newall(2:end) == 1 & newall(1:end-1) ~= 1) + 1;  % 前进行为的开始
fwd_end_idx = find(newall(1:end-1) == 1 & newall(2:end) ~= 1);  % 前进行为的结束

% 后退行为 (bwd) 的开始和结束
bwd_start_idx = find(newall(2:end) == -1 & newall(1:end-1) ~= -1) + 1;  % 后退行为的开始
bwd_end_idx = find(newall(1:end-1) == -1 & newall(2:end) ~= -1);  % 后退行为的结束

% 计算前进行为的力值变化
fwdforcechange = zeros(length(fwd_start_idx), 1);
for i = 1:length(fwd_start_idx)
    fwdforcechange(i) = force(fwd_end_idx(i)) - force(fwd_start_idx(i));  % 结束力值减去开始力值
end

% 计算后退行为的力值变化
bwdforcechange = zeros(length(bwd_start_idx), 1);
for i = 1:length(bwd_start_idx)
    bwdforcechange(i) = force(bwd_end_idx(i)) - force(bwd_start_idx(i));  % 结束力值减去开始力值
end
% 获取文件路径
% 获取文件路径


mat_file_path = fullfile(upper_folder_path, [grandparent_folder_name, '.mat']);

% 确定新的Sn名称，例如 'S1', 'S2', ... 
new_sn_name = ['S', num2str(app.dealindex)];  % 生成 'S1', 'S2', ...

% 将新数据以 cell 数组形式保存
new_sn_results = {
    'forward_frequency', forward_frequency;  % 前进蠕动频率
    'backward_frequency', backward_frequency;  % 后退蠕动频率
    'relative_forward_times', relative_forward_times;  % 相对前进蠕动发生时间
    'relative_backward_times', relative_backward_times;  % 相对后退蠕动发生时间
    'fwdforcechange', fwdforcechange;  % 前进力值变化
    'bwdforcechange', bwdforcechange;  % 后退力值变化
    'average_frequency', average_frequency;  % 平均峰值频率
    'max_intervals', max_intervals;  % 峰值最大间隔
    'min_intervals', min_intervals;  % 峰值最小间隔
    'maxforceadd', maxforceadd;  % 峰值上升最大值
    'maxforcedec', maxforcedec;  % 峰值下降最小值
    'num_peaks', num_peaks;  % 峰值个数
    'start_time', start_time;  % 峰值开始时间
    'end_time', end_time;  % 峰值结束时间
    'duration', duration;  % 峰值持续时间
    'num_rising', num_rising;  % 峰值上升次数
    'num_falling', num_falling;  % 峰值下降次数

};

% 将新 Sn 结果添加到 existing_calculation_results
app.existing_calculation_results = [app.existing_calculation_results; {new_sn_name, new_sn_results}];
updated_calculation_results=app.existing_calculation_results;
% 保存到 MAT 文件
save(mat_file_path, 'updated_calculation_results');
% 输出保存路径
disp(['Results appended and saved in MAT file: ', mat_file_path]);
app.dealindex=app.dealindex+1;


        end

        % Button pushed function: DecTrainingButton
        function DecTrainingButtonPushed(app, event)
                app.isCondition4Met = false;
            app.isCondition5Met = false;
            app.isCondition6Met = true;
            app.forcedeccal=app.forceData(app.index-1);
        end

        % Callback function
        function SmacqButtonPushed(app, event)
loadlibrary('USB3000.dll','USB3000.h')  %加载与范例文件同一层级下的dll文件与.h文件
libfunctions USB3000 -full

DevIndex = int32(0);                          %预先设置下列函数需要设置的参数值 如需要了解函数不同参数值的功能可以查看函数手册
ChanON = uint8(1);
ChanOFF = uint8(0);
AiRange = single(2.56);
AiSampleMode = uint8(0);
AiConnectType = uint8(1);
SamplePeriod = uint32(100000);
AiTrigSource = uint8(0);
AiConvSource = uint8(0);
Points = uint32(1000);
TimeOut = uint32(4000);
Data = zeros(1,160000);                                        %初始化空矩阵 长度大于等于需要能存放一次获取所有通道的数据长度
DataPtr = libpointer('singlePtr',Data);
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
calllib('USB3000','USB3OpenDevice',DevIndex)                               %打开采集卡，若使用一张采集卡索引为0，若多张采集卡使用 
                                                                           %则需要通过获取采集卡底部的SN码确定采集卡具体索引
calllib('USB3000','USB3ReadDevcieSN',DevIndex)                             %可通过执行读取SN和Model函数 通过函数返回值 获取当前索引采集卡的SN码 以及型号
calllib('USB3000','USB3ReadDevcieModel',DevIndex)
for i=1:8
    calllib('USB3000','SetUSB3AiChanSel',DevIndex,i - 1 ,ChanOFF)          % 默认开启8通道，建议是先关闭8通道 防止有通道未关的情况出现
end

for i= 1:1
    calllib('USB3000','SetUSB3AiChanSel',DevIndex,i - 1 ,ChanON)           %开启相应通道  这里开启通道Ai0
    calllib('USB3000','SetUSB3AiRange',DevIndex,i - 1 ,AiRange)            %对应设置Ai0通道的量程 详细量程参数可以查看函数手册进行不同量程设置 这里设置为10.24 代表                                                                        %Ai0通道设置为正负10.24V电压量程
end 

calllib('USB3000','SetUSB3AiSampleMode',DevIndex,AiSampleMode)             %设置采集模式 设置0为连续采集模式  1为ontshot模式  此范例为连续采集模式
calllib('USB3000','SetUSB3AiConnectType',DevIndex,AiConnectType)           %设置连接方式 设置0位为差分模式 设置1为NRSE模式 
                                                                           %如需了解不同接线模式下的接线 详情可查看产品手册
calllib('USB3000','SetUSB3AiSampleRate',DevIndex,SamplePeriod)             %此参数单位是ns（纳秒）为单位，这里设置为100000ns 对应换算频率后 10Khz   

calllib('USB3000','SetUSB3AiTrigSource',DevIndex,AiTrigSource)             %设置触发方式 这里设置0为软件触发 执行软件触发函数即可触发采集
                                                                           %如需了解其它触发源 可到函数手册进行查看 不同参数值对应的不同触发源的设置
                                                                           
calllib('USB3000','SetUSB3AiConvSource',DevIndex,AiConvSource)             %设置时钟源 这里设置为0 为Ai功能内部时钟源  及使用采集卡Ai功能采样率参数
                                                                           %由SetUSB3AiSampleRate函数决定当前的频率
                                                                           
calllib('USB3000','SetUSB3ClrAiFifo',DevIndex)                             %清空缓冲区 
calllib('USB3000','SetUSB3AiSoftTrig',DevIndex)                            %执行软件触发函数 触发采集

%此范例以执行一个USB3GetAi函数 获取触发后缓冲区的数据点数，触发函数执行后采集卡不断采集数据存像缓冲区
%如需让采集卡连续采集  则需不断执行USB3GetAi函数，不断将缓冲区的数据获取到上位机
%USB3GetAi函数的返回值 代表缓冲区剩余数据  如在不断执行USB3GetAi函数 可观察USB3GetAi函数返回值
%来了解缓冲区数据的堆积情况 返回负值则代表有错误发生 详情错误代码请查看函数手册
for j =1:100
Fifo = calllib('USB3000','USB3GetAi',DevIndex,Points,DataPtr,TimeOut)      %从缓冲区获取点数  设置为执行一次获取1000点 建议是单通道 采样率的十分之一的点数
end                                                               %将获取的点数给到指针DATAPTR中超时时间时间设置为4000ms
Data = get(DataPtr,'Value');                                               %连续采集就是连续不断执行USB3getAi函数 数据就是指针内的数据，0到999是第一个开启的通道
pause(2)                                                                   %若是多通道采集，数据就是第一个1000是开启的第一个通道的。第二个1000数据是第二个开启的通道的
myData = zeros(1,1000);                                                     
myData = Data(1:1000);                           
plot(app.UIAxes,myData)                                        
                                                    
calllib('USB3000','SetUSB3ClrAiTrigger',DevIndex)                          %在不需要再获取数据后 执行清除采集卡触发标志    
calllib('USB3000','SetUSB3ClrAiFifo',DevIndex)                             % 清空采集卡Fifo缓冲区内数据 防止下次采集卡未清空缓冲区数据导致采集数据间不正确
calllib('USB3000','USB3CloseDevice',DevIndex)                              % 关闭采集卡 一次完整的采集结束
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
clear all;
unloadlibrary('USB3000')
        end

        % Button pushed function: VideodealButton
        function VideodealButtonPushed(app, event)
[file, path] = uigetfile('*.avi', 'Select the MAT-file');
if isequal(file, 0) || isequal(path, 0)
     disp('User canceled file selection');
     return;
end
%
pixelspermm=32.0624;
load([path,'\time_force_locomotiondata.mat'])
videoFile =fullfile(path, file);
video = VideoReader(videoFile);
% 初始化视频输出
outputVideo = VideoWriter('output_with_angle_analysis.avi');
open(outputVideo);

% 初始化轨迹和角度数组
trajectory = [];
angles = [];
% 初始化前一帧的头部和尾部位置
prevHead = [];
prevTail = [];
% 定义像素与毫米的转换系数
pixelsPerMM = 32.0624;

% 初始化前一帧质心和速度数组
prevCentroid = [];
frameRate = length(timeData)/max(timeData); % 获取视频帧率
speeds = [];

while hasFrame(video)
    if isempty(prevCentroid)
        isFirstFrame=true;
    end
    % 读取当前帧
    frame = readFrame(video);
    grayFrame = 255 - rgb2gray(frame);
    binaryFrame = imbinarize(grayFrame, "global");
    binaryFrame = bwareaopen(binaryFrame, 50);
    se = strel('disk', 5);
    binaryFrame = imclose(binaryFrame, se);

    stats = regionprops(binaryFrame, 'Centroid', 'Orientation', 'PixelList');
    if ~isempty(stats)
        centroid = stats(1).Centroid;
        trajectory = [trajectory; centroid];

        % 计算速度
        if ~isempty(prevCentroid)
            % 质心位移（毫米）
            displacement = norm(centroid - prevCentroid) / pixelsPerMM;
            % 速度 = 位移 / 时间间隔
            speed = displacement * frameRate; % mm/s
        else
            speed = 0; % 第一帧速度为0
        end

        % 存储速度
        speeds = [speeds; speed];
        prevCentroid = centroid;

        % 头尾计算与可视化（保持之前的代码逻辑）
        orientation = stats(1).Orientation;
        perimeter = bwperim(binaryFrame);

        [edgeY, edgeX] = find(perimeter);
        edgePoints = [edgeX, edgeY];
% 获取二值图像的连通域
CC = bwconncomp(binaryFrame); % 计算连通域
numPixels = cellfun(@numel, CC.PixelIdxList); % 每个连通域的像素数
[~, idx] = max(numPixels); % 找到最大的连通域索引

% 创建一个只包含最大连通域的图像
cleanBinaryFrame = false(size(binaryFrame));
cleanBinaryFrame(CC.PixelIdxList{idx}) = true;

% 重新提取清理后的边缘点
perimeter = bwperim(cleanBinaryFrame); % 提取清理后边界
[edgeY, edgeX] = find(perimeter); % 获取边缘点坐标
edgePoints = [edgeX, edgeY]; % 重新存储为二维坐标数组

        distances = vecnorm(edgePoints - centroid, 2, 2);
        [~, maxIdx] = max(distances);
        head = edgePoints(maxIdx, :);
        reldis = norm(centroid - head);

        remainingPoints = [];
        for i = 1:size(edgePoints, 1)
            point = edgePoints(i, :);
            if norm(point - head) > reldis
                remainingPoints = [remainingPoints; point];
            end
        end

        if ~isempty(remainingPoints)
            distancesToCentroid = vecnorm(remainingPoints - centroid, 2, 2);
            [~, maxTailIdx] = max(distancesToCentroid);
            tail = remainingPoints(maxTailIdx, :);
        else
            tail = centroid;
        end

        if ~isempty(prevHead) && ~isempty(prevTail)
            distHeadToPrevHead = norm(head - prevHead);
            distHeadToPrevTail = norm(head - prevTail);
            distTailToPrevHead = norm(tail - prevHead);
            distTailToPrevTail = norm(tail - prevTail);
            
            if distHeadToPrevTail + distTailToPrevHead < distHeadToPrevHead + distTailToPrevTail
                temp = head;
                head = tail;
                tail = temp;
            end
        end


        vectorHead = head - centroid;
        vectortail = centroid-tail;
        angleDiff = atan2d(vectorHead(2), vectorHead(1))-atan2d(vectortail(2), vectortail(1));
        angles = [angles; angleDiff];
    end

    % 可视化
    figure(1);
    imshow(frame);
    hold on;
    if ~isempty(trajectory)
        plot(trajectory(:,1), trajectory(:,2), 'r-', 'LineWidth', 2);
    end
    if exist('head', 'var') && exist('tail', 'var')
        plot(head(1), head(2), 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b');
        plot(tail(1), tail(2), 'go', 'MarkerSize', 10, 'MarkerFaceColor', 'g');
        text(centroid(1), centroid(2), sprintf('Angle: %.2f', angleDiff), 'Color', 'yellow');
        text(centroid(1), centroid(2)-20, sprintf('speed: %.2f', speed), 'Color', 'red');
    end
    hold off;
    if isFirstFrame
        userChoice = questdlg('Do you want to swap head and tail?', ...
            'Swap Head and Tail', 'Yes', 'No', 'No');
        if strcmp(userChoice, 'Yes')
            % 交换 head 和 tail
            temp = head;
            head = tail;
            tail = temp;

            % 重新可视化
            figure(1);
            imshow(frame);
            hold on;
            if ~isempty(trajectory)
                plot(trajectory(:,1), trajectory(:,2), 'r-', 'LineWidth', 2);
            end
            plot(head(1), head(2), 'bo', 'MarkerSize', 10, 'MarkerFaceColor', 'b');
            plot(tail(1), tail(2), 'go', 'MarkerSize', 10, 'MarkerFaceColor', 'g');
            text(centroid(1), centroid(2), sprintf('Speed: %.2f', speed), 'Color', 'red');
            hold off;
        end
    end

        prevHead = head;
        prevTail = tail;
    isFirstFrame = false;
    frameWithAnalysis = getframe(gca);
    writeVideo(outputVideo, frameWithAnalysis.cdata);
end

% 平滑速度数据
smoothSpeeds = smoothdata(speeds, 'movmean', 5); % 使用5点移动平均平滑

% 更新后的绘图部分
timeData = timeData(1:length(smoothSpeeds));
forceData = forceData(1:length(smoothSpeeds));
locomotionData = locomotionData(1:length(smoothSpeeds));

figure('Position', [100, 100, 1920, 1080]); % 图窗大小设置为 1920x1080

% 子图 1：绘制力数据
subplot(3, 1, 1); % 3 行 1 列，第 1 个子图
plot(timeData, forceData, 'b-', 'LineWidth', 1.5);
xlabel('Time');
ylabel('Force');
title('Force over Time');
grid on;

% 子图 2：绘制平滑后的速度数据
subplot(3, 1, 2); % 3 行 1 列，第 2 个子图
plot(timeData, smoothSpeeds, 'r-', 'LineWidth', 1.5);
xlabel('Time');
ylabel('Speed (mm/s)');
title('Smoothed Centroid Speed over Time');
grid on;

% 子图 3：绘制角度数据
subplot(3, 1, 3); % 3 行 1 列，第 3 个子图
plot(timeData, angles, 'g-', 'LineWidth', 1.5);
xlabel('Time');
ylabel('Angle (degrees)');
title('Angle Variation over Time');
grid on;

% 调整子图布局，确保不重叠
sgtitle('Analysis Plots'); % 添加总标题

% 保存图像到指定路径
saveas(gcf, fullfile(path, 'analysis_plots.png')); % 保存为 PNG 图像
saveas(gcf, fullfile(path, 'analysis_plots.fig')); % 保存为 MATLAB 可编辑文件

        end

        % Button pushed function: ShowpicButton
        function ShowpicButtonPushed(app, event)
            larvaframe=screencapture(0,[0,-80,1200,1080]);
            app.Image.ImageSource=larvaframe;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Larva comperssion')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Force(mN)')
            zlabel(app.UIAxes, 'Z')
            app.ForceLabel = uilabel(app.UIFigure);
            app.ForceLabel.Text = 'Force';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ControlButton = uibutton(app.UIFigure, 'push');
            app.ControlButton.ButtonPushedFcn = createCallbackFcn(app, @ControlButtonPushed, true);
            app.ControlButton.Text = 'Control';
            app.AimforcemNEditFieldLabel = uilabel(app.UIFigure);
            app.AimforcemNEditFieldLabel.Text = 'Aim force(mN)';
            app.AimforcemNEditField = uieditfield(app.UIFigure, 'numeric');
            app.AimforcemNEditField.Value = 25;
            app.LocomotionLabel = uilabel(app.UIFigure);
            app.LocomotionLabel.Text = 'Locomotion:';
            app.LocomotionTextArea = uitextarea(app.UIFigure);
            app.LocomotionTextArea.Value = {'STATIC'};
            app.TrainStartButton = uibutton(app.UIFigure, 'push');
            app.TrainStartButton.ButtonPushedFcn = createCallbackFcn(app, @TrainStartButtonPushed, true);
            app.TrainStartButton.Text = 'TrainStart';
            app.TrainControlButton = uibutton(app.UIFigure, 'push');
            app.TrainControlButton.ButtonPushedFcn = createCallbackFcn(app, @TrainControlButtonPushed, true);
            app.TrainControlButton.Text = 'TrainControl';
            app.TrainingButton = uibutton(app.UIFigure, 'push');
            app.TrainingButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingButtonPushed, true);
            app.TrainingButton.Text = 'Training';
            app.SavingpathTextAreaLabel = uilabel(app.UIFigure);
            app.SavingpathTextAreaLabel.Text = 'Saving path';
            app.SavingpathTextArea = uitextarea(app.UIFigure);
            app.SavingpathTextArea.Value = {'D:\dym\Force_larva\Data\CS\Temp'};
            app.SampleindexEditFieldLabel = uilabel(app.UIFigure);
            app.SampleindexEditFieldLabel.Text = 'Sample index';
            app.SampleindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleindexEditField.Value = 1;
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.TrainAimmNEditFieldLabel = uilabel(app.UIFigure);
            app.TrainAimmNEditFieldLabel.Text = 'Train Aim (mN)';
            app.TrainAimmNEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrainAimmNEditField.Value = 50;
            app.TrainStepmNEditFieldLabel = uilabel(app.UIFigure);
            app.TrainStepmNEditFieldLabel.Text = 'Train Step (mN)';
            app.TrainStepmNEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrainStepmNEditField.Value = 5;
            app.OutputEditFieldLabel = uilabel(app.UIFigure);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.UIFigure, 'text');
            app.ResultshowButton = uibutton(app.UIFigure, 'push');
            app.ResultshowButton.ButtonPushedFcn = createCallbackFcn(app, @ResultshowButtonPushed, true);
            app.ResultshowButton.Text = 'Result show';
            app.DatadealButton = uibutton(app.UIFigure, 'push');
            app.DatadealButton.ButtonPushedFcn = createCallbackFcn(app, @DatadealButtonPushed, true);
            app.DatadealButton.Text = 'Data deal';
            app.DecTrainingButton = uibutton(app.UIFigure, 'push');
            app.DecTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @DecTrainingButtonPushed, true);
            app.DecTrainingButton.Text = 'DecTraining';
            app.VideodealButton = uibutton(app.UIFigure, 'push');
            app.VideodealButton.ButtonPushedFcn = createCallbackFcn(app, @VideodealButtonPushed, true);
            app.VideodealButton.Text = 'Video deal';
            app.Image = uiimage(app.UIFigure);
            app.ShowpicButton = uibutton(app.UIFigure, 'push');
            app.ShowpicButton.ButtonPushedFcn = createCallbackFcn(app, @ShowpicButtonPushed, true);
            app.ShowpicButton.Text = 'Showpic';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Forcecal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end